classdef app_2048 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GamePanel           matlab.ui.container.Panel
        ScoreValueText      matlab.ui.control.Label
        ScoreLabel          matlab.ui.control.Label
        BackToMenuGame      matlab.ui.control.Button
        GameContainer       matlab.ui.container.Panel
        TileBlock           matlab.ui.control.Label
        GameOverPanel       matlab.ui.container.Panel
        BackToMenuGameOver  matlab.ui.control.Button
        GameOverText        matlab.ui.control.Label
        MenuPanel           matlab.ui.container.Panel
        MenuGrid            matlab.ui.container.GridLayout
        PlayButtonPanel     matlab.ui.container.Panel
        PlayButton          matlab.ui.control.Button
        SliderPanel         matlab.ui.container.Panel
        SliderLabel         matlab.ui.control.Label
        SliderValueText     matlab.ui.control.Label
        Slider              matlab.ui.control.Slider
        MenuTitle           matlab.ui.control.Label
    end

    
    properties (Access = private)
        Grid double   
        score double;
        nCells double;
        tileSize double;
        GameOver logical                    
        TileLabels matlab.ui.control.Label  
    end
    
    methods (Access = private)
        function createTileLabels(app)
            panelW = 401;
            panelH = 401;
            padding = 6;  
            n = app.nCells;         
            app.tileSize = (panelW - (n+1)*padding) / n;

            app.TileLabels = matlab.ui.control.Label.empty;

            for row = 1:n
                for col = 1:n
                    % Vypočítat pozici (x,y) uvnitř panelu
                    x = padding + (col-1)*(app.tileSize + padding);
                    y = panelH - padding - row*(app.tileSize + padding) + padding;

                    % Vytvořit nový label ze šablony
                    lbl = uilabel(app.GameContainer);
                    lbl.FontSize = app.getFontSize(1);
                    lbl.FontWeight = app.TileBlock.FontWeight;
                    lbl.HorizontalAlignment = app.TileBlock.HorizontalAlignment;
                    lbl.VerticalAlignment = app.TileBlock.VerticalAlignment;
                    lbl.FontColor = app.TileBlock.FontColor;
                    lbl.FontName = app.TileBlock.FontName;
                    lbl.Position = [x y app.tileSize app.tileSize];
                    lbl.BackgroundColor = '#bdac97';
                    lbl.Text = '';
                    
                    idx = (row-1)*n + col;
                    app.TileLabels(idx) = lbl;
                end
            end
        end
        
        function setStartingTiles(app)
            for i=1:2
                app.spawnNewTile();
            end
            app.updateAllTiles();
        end
        
        function spawnNewTile(app)
            emptyIndices = find(app.Grid == 0);
            if isempty(emptyIndices)
                disp('ran out of grid space, game over');
                return;
            end

            idx = emptyIndices(randi(numel(emptyIndices)));
            [row, col] = ind2sub(size(app.Grid), idx);

            if rand() < 0.1
                value = 4;
            else
                value = 2;
            end

            app.Grid(row, col) = value;
        end

        function bgColor = getValueColor(~, val)
            switch val
                case 0
                    bgColor = '#bdac97';
                case 2
                    bgColor = '#eee4da';
                case 4
                    bgColor = '#ebd8b6';
                case 8
                    bgColor = '#f2ae73';
                case 16
                    bgColor = '#f29667';
                case 32
                    bgColor = '#f27c62';
                case 64
                    bgColor = '#f35f41';
                case 128
                    bgColor = '#eacf76';
                case 256
                    bgColor = '#edcb67';
                case 512
                    bgColor = '#ecc85a';
                case 1024
                    bgColor = '#e7c257';
                case 2048
                    bgColor = '#e8be4e';
                otherwise
                    % Pro hodnoty větší než 2048
                    bgColor = '#b03cc7';
            end
        end

        function updateAllTiles(app)
            for r = 1:app.nCells
                for c = 1:app.nCells
                    idx = (r - 1)*app.nCells + c;
                    val = app.Grid(r, c);
                    app.updateTileValue(idx, val);
                end
            end
        end
        
        function updateTileValue(app, pos, val)
            if val == 0
                app.TileLabels(pos).Text = "";
            elseif val <= 4
                app.TileLabels(pos).Text = string(val);
                app.TileLabels(pos).FontColor = '#756452';
            else
                app.TileLabels(pos).Text = string(val);
                app.TileLabels(pos).FontColor = '#ffffff';
            end

            app.TileLabels(pos).FontSize = app.getFontSize(length(num2str(val)));
        
            bgColor = app.getValueColor(val);

            % Set the background color
            app.TileLabels(pos).BackgroundColor = bgColor;
        end

        function fontSize = getFontSize(app, numDigits)
            baseFactor = 1.8;    
            scaleFactor = 0.4; 
            
            fontSize = floor(app.tileSize / (numDigits^scaleFactor * baseFactor));
        end

        function SwitchScreen(app, targetPanel)
            allPanels = [app.MenuPanel, app.GamePanel, app.GameOverPanel];
            for p = allPanels
                p.Visible = 'off';
            end
            targetPanel.Visible = 'on';
        end
        
        function GameSetup(app)
            app.nCells = app.Slider.Value;
            app.score = 0;
            app.ScoreValueText.Text = "0";

            % Inicializace herního stavu a UI
            app.Grid = zeros(app.nCells,app.nCells);

            app.GameOver = false;

            app.createTileLabels();
            app.setStartingTiles();
        end

        function dead = isGameOver(app)
            g = app.Grid;            
            n = app.nCells; 
        
            % 1) If any zero, not dead
            if any(g(:) == 0)
                dead = false;
                return
            end
        
            % 2) Check horizontal & vertical neighbors
            for r = 1:n
                for c = 1:n
                    % check right neighbor (if exists)
                    if c < n && g(r,c) == g(r,c+1)
                        dead = false;
                        return
                    end
                    % check down neighbor (if exists)
                    if r < n && g(r,c) == g(r+1,c)
                        dead = false;
                        return
                    end
                end
            end
        
            % 3) If we got here, no moves left
            dead = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Smazat šablonový label
            app.TileBlock.Visible = 'off';
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            moved = false;
            countedScore = 0;

            function [outLine, changed, lineScore] = shiftMerge(line)
                nums = line(line ~= 0);
                merged = [];
                lineScore = 0;
                i = 1;
                while i <= numel(nums)
                    if i < numel(nums) && nums(i) == nums(i+1)
                        newValue = nums(i)*2;
                        merged(end+1) = newValue;
                        lineScore = lineScore + newValue;
                        i = i + 2;
                    else
                        merged(end+1) = nums(i); 
                        i = i + 1;
                    end
                end
                outLine = [merged, zeros(1, app.nCells - numel(merged))];
                changed = any(outLine ~= line);
            end
       
            switch key
                case 'leftarrow'
                    for r = 1:app.nCells
                        [newLine, didChange, lineScore] = shiftMerge(app.Grid(r, :));
                        if didChange
                            app.Grid(r, :) = newLine;
                            moved = true;
                            countedScore = countedScore + lineScore;
                        end
                    end
    
                case 'rightarrow'
                    for r = 1:app.nCells
                        rowRev = fliplr(app.Grid(r, :));
                        [mergedRev, didChange, lineScore] = shiftMerge(rowRev);
                        newLine = fliplr(mergedRev);
                        if didChange
                            app.Grid(r, :) = newLine;
                            moved = true;
                            countedScore = countedScore + lineScore;
                        end
                    end
    
                case 'uparrow'
                    for c = 1:app.nCells
                        colVec = app.Grid(:, c)';
                        [newCol, didChange, lineScore] = shiftMerge(colVec);
                        if didChange
                            app.Grid(:, c) = newCol';
                            moved = true;
                            countedScore = countedScore + lineScore;
                        end
                    end
    
                case 'downarrow'
                    for c = 1:app.nCells
                        colRev = fliplr(app.Grid(:, c)');
                        [mergedRev, didChange, lineScore] = shiftMerge(colRev);
                        newCol = fliplr(mergedRev)';
                        if didChange
                            app.Grid(:, c) = newCol;
                            moved = true;
                            countedScore = countedScore + lineScore;
                        end
                    end   
                otherwise
                    return 
            end
    
            if moved
                app.spawnNewTile();
                app.updateAllTiles();
                app.score = app.score + countedScore;
                disp(app.score);
                app.ScoreValueText.Text = num2str(app.score);
            end

            if app.isGameOver()
                app.SwitchScreen(app.GameOverPanel);
            end
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            app.Slider.Value = round(app.Slider.Value);
            app.SliderValueText.Text = string(app.Slider.Value);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.SwitchScreen(app.GamePanel);
            app.GameSetup();
        end

        % Button pushed function: BackToMenuGame
        function BackToMenuGameButtonPushed(app, event)
            app.SwitchScreen(app.MenuPanel);
            delete(app.TileLabels);
            app.TileLabels = matlab.ui.control.Label.empty;
        end

        % Button pushed function: BackToMenuGameOver
        function BackToMenuGameOverButtonPushed(app, event)
            app.SwitchScreen(app.MenuPanel);
            delete(app.TileLabels);
            app.TileLabels = matlab.ui.control.Label.empty;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.MenuPanel = uipanel(app.UIFigure);
            app.MenuGrid = uigridlayout(app.MenuPanel);
            app.MenuTitle = uilabel(app.MenuGrid);
            app.MenuTitle.Text = '2048 Game';
            app.SliderPanel = uipanel(app.MenuGrid);
            app.Slider = uislider(app.SliderPanel);
            app.Slider.Limits = [2 10];
            app.Slider.MajorTicks = [2 3 4 5 6 7 8 9 10];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.MinorTicks = [2 3 4 5 6 7 8 9 10];
            app.Slider.Value = 4;
            app.SliderValueText = uilabel(app.SliderPanel);
            app.SliderValueText.Text = '4';
            app.SliderLabel = uilabel(app.SliderPanel);
            app.SliderLabel.Text = 'Select the number of tiles per side:';
            app.PlayButtonPanel = uipanel(app.MenuGrid);
            app.PlayButton = uibutton(app.PlayButtonPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.GameOverPanel = uipanel(app.UIFigure);
            app.GameOverPanel.Visible = 'off';
            app.GameOverText = uilabel(app.GameOverPanel);
            app.GameOverText.Text = 'Game Over';
            app.BackToMenuGameOver = uibutton(app.GameOverPanel, 'push');
            app.BackToMenuGameOver.ButtonPushedFcn = createCallbackFcn(app, @BackToMenuGameOverButtonPushed, true);
            app.BackToMenuGameOver.Text = 'Back to menu';
            app.GamePanel = uipanel(app.UIFigure);
            app.GamePanel.Visible = 'off';
            app.GameContainer = uipanel(app.GamePanel);
            app.TileBlock = uilabel(app.GameContainer);
            app.TileBlock.Text = '2';
            app.BackToMenuGame = uibutton(app.GamePanel, 'push');
            app.BackToMenuGame.ButtonPushedFcn = createCallbackFcn(app, @BackToMenuGameButtonPushed, true);
            app.BackToMenuGame.Text = 'Back to menu';
            app.ScoreLabel = uilabel(app.GamePanel);
            app.ScoreLabel.Text = 'Score: ';
            app.ScoreValueText = uilabel(app.GamePanel);
            app.ScoreValueText.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_2048

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end